classdef GmshGridParsing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeshInfUIFigure  matlab.ui.Figure
        OutPutMeshInf    matlab.ui.control.Button
        SelectedFile     matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
        OpenFile         matlab.ui.control.Button
    end

    properties (Access = public)
        file = "";
        path = "";
        FullFile = "";
        msgbox_style = struct('WindowStyle','modal', 'Interpreter', 'tex');
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFile
        function OpenFilePushed(app, event)
            [app.file,app.path] = uigetfile('*.msh');
            app.FullFile = fullfile(app.path, app.file);
            app.SelectedFile.Value = app.FullFile;
        end

        % Value changed function: SelectedFile
        function SelectedFileValueChanged(app, event)
            app.FullFile = string(app.SelectedFile.Value);
        end

        % Button pushed function: OutPutMeshInf
        function OutPutMeshInfPushed(app, event)
            if(app.FullFile ~= "")
                try 
                    fopen(app.FullFile);
                    output_path = uigetdir();
                    MeshInfExtract(app.FullFile, output_path);
                    msgbox("\fontsize{13} 网格信息输出成功！",'提示信息','help', app.msgbox_style);
                catch
                    msgbox("\fontsize{13} 文件路径不正确或该文件不存在！",'错误信息','error', app.msgbox_style);
                end
            else
                msgbox("\fontsize{13} 请输入文件路径！",'警告信息','warn', app.msgbox_style);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeshInfUIFigure = uifigure('Visible', 'off');
            app.MeshInfUIFigure.Name = 'MeshInf';
            app.MeshInfUIFigure.Icon = 'logo.png';
            app.OpenFile = uibutton(app.MeshInfUIFigure, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFilePushed, true);
            app.OpenFile.Text = '打开';
            app.Label = uilabel(app.MeshInfUIFigure);
            app.Label.Text = '所选文件';
            app.SelectedFile = uitextarea(app.MeshInfUIFigure);
            app.SelectedFile.ValueChangedFcn = createCallbackFcn(app, @SelectedFileValueChanged, true);
            app.OutPutMeshInf = uibutton(app.MeshInfUIFigure, 'push');
            app.OutPutMeshInf.ButtonPushedFcn = createCallbackFcn(app, @OutPutMeshInfPushed, true);
            app.OutPutMeshInf.Text = '输出网格信息文件';
            % Show the figure after all components are created
            app.MeshInfUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GmshGridParsing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeshInfUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeshInfUIFigure)
        end
    end
end